classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResultsLabel                    matlab.ui.control.Label
        CorrespondingchaincodeTextArea_3  matlab.ui.control.TextArea
        CorrespondingchaincodeTextArea_3Label  matlab.ui.control.Label
        CorrespondingchaincodeTextArea_2  matlab.ui.control.TextArea
        CorrespondingchaincodeTextArea_2Label  matlab.ui.control.Label
        CorrespondingchaincodeTextArea  matlab.ui.control.TextArea
        CorrespondingchaincodeLabel     matlab.ui.control.Label
        ControlsPanel                   matlab.ui.container.Panel
        DetectButton                    matlab.ui.control.Button
        ChoosefileButton                matlab.ui.control.Button
        ImagetodetectEditField          matlab.ui.control.EditField
        ImagetodetectEditFieldLabel     matlab.ui.control.Label
        UIAxes_Mask_3                   matlab.ui.control.UIAxes
        UIAxes_Mask_2                   matlab.ui.control.UIAxes
        UIAxes_Mask                     matlab.ui.control.UIAxes
        UIAxes_Image                    matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = detectStain(app, img)
            
            img = imresize(img, 0.2);
            [H,S,V]  = rgb2hsv(img);
            H = imgaussfilt(H, 3);
            S = imgaussfilt(S, 3);
            
            h_thresh = H >= 0.014 & H < 224;
            s_thresh = S >= 0.39;
            v_thresh = V >= 0.4;

            thresh1 = h_thresh & s_thresh & v_thresh;
            img_fill = imfill(thresh1, "holes");
            SE1 = strel('disk', 5);
            img_morphology = imopen(img_fill, SE1);
            img_morphology = imdilate(img_morphology, SE1);
            results = img_morphology;
        end
        
        function results = detectIncompleteStitch(app, img)
            img = imresize(img, 0.15);
            img_gray = rgb2gray(img);
            img_power = imadjust(img_gray, [], [], 6);
            img_gauss = imgaussfilt(img_power, 1);
            img_thresh = img_gauss > 80;


            img_clean = bwmorph(img_thresh, "clean");
            
            SE1 = strel('square',2);
            SE2 = strel('diamond',4);
            
            img_opened = imopen(img_clean, SE1);
            defective_area = imdilate(img_opened, SE2);
            defective_area = imdilate(defective_area, SE2);
            defective_area = imdilate(defective_area, SE2);

            results = defective_area;
        end
        
        function results = detectMissingFinger(app, img)
            img_resized = imresize(img, 0.2);
            [H,S,V] = rgb2hsv(img_resized);
            s_gauss = imgaussfilt(S, 10);
            h_thresh = H >= 0.879 | H < 0.088;
            s_thresh = s_gauss >= 0.272;
            thresh1 = h_thresh & s_thresh;
            img_fill = imclearborder(thresh1);
            SE1 = strel('disk', 10);
            img_open = imopen(img_fill, SE1);
            img_dilated = imdilate(img_open, SE1);

            results = img_dilated;

        end
        
        function [cc] = chaincode(app, b,unwrap)
        %   Freeman Chain Code
        %
        %   Description: Give Freeman chain code 8-connected representation of a
        %                boundary
        %   Author.....: Alessandro Mannini <mannini@esod.it>
        %   Date.......: 2010, september
        %
        %   usage:
        %   --------------------------------------------------------
        %   [cc] = chaincode(b,u)
        %
        %   INPUT:
        %   --------------------------------------------------------
        %   b       - boundary as np-by-2 array; 
        %             np is the number of pixels and each element is a pair (y,x) of
        %             pixel coordinates
        %   unwrap  - (optional, default=false) unwrap code;
        %             if enable phase inversions are eliminated
        %            
        %
        %   OUTPUT:
        %   --------------------------------------------------------
        %   cc is structure with the following fields:
        %
        %   cc.code     - 8-connected Freeman chain code as 1-by-np array (or
        %                 1-by-(np-1) if the boundary isn't close)
        %   cc.x0,cc.y0 - respectively the abscissa and ordinate of start point
        %   cc.ucode    - unwrapped 8-connected Freeman chain code (if required)
        %
        
        %
        %
        %   used direction-to-code convention is:       3  2  1
        %                                                \ | /
        %                                             4 -- P -- 0
        %                                                / | \
        %                                               5  6  7
        %   
        %   and in terms of deltax,deltay if next pixel compared to the current:
        %   --------------------------
        %   | deltax | deltay | code |
        %   |------------------------|
        %   |    0   |   +1   |   2  |
        %   |    0   |   -1   |   6  |
        %   |   -1   |   +1   |   3  |
        %   |   -1   |   -1   |   5  |
        %   |   +1   |   +1   |   1  |
        %   |   +1   |   -1   |   7  |
        %   |   -1   |    0   |   4  |
        %   |   +1   |    0   |   0  |
        %   --------------------------
        %
        
        % check input arguments
        if nargin>3 
            error('Too many arguments');
        elseif nargin==1
            error('Too few arguments');
        elseif nargin==2
            unwrap=false;
        end    
        % compute dx,dy  by a circular shift on coords arrays by 1 element
        sb=circshift(b,[-1 0]);
        delta=sb-b;
        % check if boundary is close, if not cut last element
        if abs(delta(end,1))>1 || abs(delta(end,2))>1
            delta=delta(1:(end-1),:);
        end
        % check if boundary is 8-connected
        n8c=find(abs(delta(:,1))>1 | abs(delta(:,2))>1);
        if size(n8c,1)>0 
            s='';
            for i=1:size(n8c,1)
                s=[s sprintf(' idx -> %d \n',n8c(i))];
            end
            error('Curve isn''t 8-connected in elements: \n%s',s);
        end
        
        
        % convert dy,dx pairs to scalar indexes thinking to them (+1) as base-3 numbers
        % according to: idx=3*(dy+1)+(dx+1)=3dy+dx+4 (adding 1 to have idx starting
        % from 1)
        % Then use a mapping array cm
        %   --------------------------------------
        %   | deltax | deltay | code | (base-3)+1 |
        %   |-------------------------------------|
        %   |    0   |   +1   |   2  |      8     | 
        %   |    0   |   -1   |   6  |      2     | 
        %   |   -1   |   +1   |   3  |      7     | 
        %   |   -1   |   -1   |   5  |      1     | 
        %   |   +1   |   +1   |   1  |      9     | 
        %   |   +1   |   -1   |   7  |      3     | 
        %   |   -1   |    0   |   4  |      4     |  
        %   |   +1   |    0   |   0  |      6     | 
        %   ---------------------------------------
        
        idx=3*delta(:,1)+delta(:,2)+5;
        cm([1 2 3 4 6 7 8 9])=[5 6 7 4 0 3 2 1];
        
        % finally the chain code array and the starting point
        cc.x0=b(1,2);
        cc.y0=b(1,1);
        cc.code=(cm(idx))';
        
        % If unwrapping is required, use the following algorithm
        %
        % if a(k), k=1..n is the original code and u(k) the unwrapped:
        %
        % - u(1)=a(1)
        % - u(k)=g(k), 
        %        g(k) in Z | (g(k)-a(k)) mod 8=0 and |g(k)-u(k-1)| is minimized  
        %
        if (unwrap) 
            a=cc.code;
            u(1)=a(1);
            la=size(a,1);
            for i=2:la
                n=round((u(i-1)-a(i))/8);
                u(i)=a(i)+n*8;
            end
            cc.ucode=u';
        end    
        
        end
    
        function cc = generate_chaincode(app, mask)
            [r, c] = find(mask, 1, 'first');

            if ~isempty(r)
                contour = bwtraceboundary(mask, [r c], 'E', 8);
            
                res = app.chaincode(contour);
                cc = res.code;
            else
                cc = [""];
            end
        end
        
        function results = matchcode(app, code)
            disp(code);
            stains = [
            "7666777777777777000007667777777777767667777700076766677777707777070777077070707070707070700070000000000000100000000010011011000010010010101010111010110111112122111111122222222232332222222222223233233333332322333334443443223222323333433333333332211222222222333344444444444343434333333434443434333344444444443444343444444444444444445454554554545445455555555556656666666656656566565656565665666666666666666666660" ...
            "7667666566666666666667666776777776776776777777707707000000000000000070007000700700700000000000000010000001011010111121211111222222221111112101111221111112222211101111122121121221221122122212212212222122122222222222232223223223232223223233333323223233233233333343323333343434444433333344444444545555554444444445455555656555656545455556666666665454454555556665656555656555344444444455556566666666767666656655656566666666655656666666660"
            ];
            
            fingers = [
            "77677777777777707770707070707000000000000000000000001000100010001000010001000010010001001001001001000100000100000001000000000000000000000000000000000000001000000001000001000100101011111111112222222222222222222223233333333433434434434444434444444444444444444444444444544444444445444444444544444444445444444444444544444444444544444444454444445444444544445444445444445444444544444544444544454454555555555666666666666666666660"...
            "7777777770700700700070070007000070000070000000000000000007000000070007000070007000007000000000700000000000000000000000000000001000000100010111111111222222222222222222223333333343434434434444344434444434444443444444444434444444444434444434443443443434434344344344344344434444344444444444444444444454454554555555555655656666666666666666666660"
            ];

            stitches = [
            "777777777777700111111111111231112231121111112111111112233333333333344444444334555555555555666556755566656765566556675560"...  
            "7777777777777001111111111112231112231112111111111112111123333333333334445344443344455555555555567656666555675666755665556765560"
            ];

            if ismember(code, stains)
                results = "Stain defect";
            elseif ismember(code, fingers)
                results = "Missing finger defect";
            elseif ismember(code, stitches)
                results = "Incomplete stitch defect";
            else
                results = "";
            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChoosefileButton
        function ChoosefileButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            if ~(isequal(path,0) || isequal(file, 0))
                original_img = imread(strcat(path, file));
                app.ImagetodetectEditField.Value = strcat(path, file);
                imshow(original_img, 'Parent', app.UIAxes_Image);
            else 
                disp("No image chosen.")
            end 
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            % clear 
            app.CorrespondingchaincodeTextArea.Value = '';
            app.CorrespondingchaincodeTextArea_2.Value = '';
            app.CorrespondingchaincodeTextArea_3.Value = '';
            cla(app.UIAxes_Mask, 'reset');
            cla(app.UIAxes_Mask_2, 'reset');
            cla(app.UIAxes_Mask_3, 'reset');


            img = imread(app.ImagetodetectEditField.Value);
            mask1 = app.detectStain(img);
            mask2 = app.detectIncompleteStitch(img);
            mask3 = app.detectMissingFinger(img);
            imshow(mask1, "Parent", app.UIAxes_Mask);
            imshow(mask2, "Parent", app.UIAxes_Mask_2);
            imshow(mask3, "Parent", app.UIAxes_Mask_3);
            cc1 = strjoin(string(app.generate_chaincode(mask1)), '');
            cc2 = strjoin(string(app.generate_chaincode(mask2)), '');
            cc3 = strjoin(string(app.generate_chaincode(mask3)), '');
            app.CorrespondingchaincodeTextArea.Value = cc1;
            app.CorrespondingchaincodeTextArea_2.Value = cc2;
            app.CorrespondingchaincodeTextArea_3.Value = cc3;

            defect = app.matchcode(cc1);
            if defect == ""
                defect = app.matchcode(cc2);
            end
            if defect == ""
                defect = app.matchcode(cc3);
            end
            if defect == ""
                defect = "No registered defect found";
            end


            app.ResultsLabel.Text = defect;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Image = uiaxes(app.UIFigure);
            title(app.UIAxes_Image, 'Image')
            app.UIAxes_Mask = uiaxes(app.UIFigure);
            title(app.UIAxes_Mask, 'Mask 1')
            app.UIAxes_Mask_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_Mask_2, 'Mask 2')
            app.UIAxes_Mask_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_Mask_3, 'Mask 3')
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.ImagetodetectEditFieldLabel = uilabel(app.ControlsPanel);
            app.ImagetodetectEditFieldLabel.Text = 'Image to detect';
            app.ImagetodetectEditField = uieditfield(app.ControlsPanel, 'text');
            app.ImagetodetectEditField.Editable = 'off';
            app.ChoosefileButton = uibutton(app.ControlsPanel, 'push');
            app.ChoosefileButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefileButtonPushed, true);
            app.ChoosefileButton.Text = 'Choose file';
            app.DetectButton = uibutton(app.ControlsPanel, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.CorrespondingchaincodeLabel = uilabel(app.UIFigure);
            app.CorrespondingchaincodeLabel.Text = {'Corresponding '; 'chaincode'};
            app.CorrespondingchaincodeTextArea = uitextarea(app.UIFigure);
            app.CorrespondingchaincodeTextArea.Editable = 'off';
            app.CorrespondingchaincodeTextArea_2Label = uilabel(app.UIFigure);
            app.CorrespondingchaincodeTextArea_2Label.Text = {'Corresponding '; 'chaincode'};
            app.CorrespondingchaincodeTextArea_2 = uitextarea(app.UIFigure);
            app.CorrespondingchaincodeTextArea_2.Editable = 'off';
            app.CorrespondingchaincodeTextArea_3Label = uilabel(app.UIFigure);
            app.CorrespondingchaincodeTextArea_3Label.Text = {'Corresponding '; 'chaincode'};
            app.CorrespondingchaincodeTextArea_3 = uitextarea(app.UIFigure);
            app.CorrespondingchaincodeTextArea_3.Editable = 'off';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results : ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end